classdef Power < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Task_1Tab                      matlab.ui.container.Tab
        InputsPanel                    matlab.ui.container.Panel
        conductorresistivityEditFieldLabel  matlab.ui.control.Label
        conductorresistivityEditField  matlab.ui.control.NumericEditField
        conductorlengthKmEditFieldLabel  matlab.ui.control.Label
        conductorlengthKmEditField     matlab.ui.control.NumericEditField
        ConductordiametercmEditFieldLabel  matlab.ui.control.Label
        ConductordiametercmEditField   matlab.ui.control.NumericEditField
        FrequencyHZEditFieldLabel      matlab.ui.control.Label
        FrequencyHZEditField           matlab.ui.control.NumericEditField
        DinputPanel                    matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        SymmetricButton                matlab.ui.control.RadioButton
        UnsymmetricButton              matlab.ui.control.RadioButton
        DistancemEditFieldLabel        matlab.ui.control.Label
        DistancemEditField             matlab.ui.control.NumericEditField
        EnterButton                    matlab.ui.control.Button
        numberofconductorsEditFieldLabel  matlab.ui.control.Label
        numberofconductorsEditField    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        ResistanceDCLabel              matlab.ui.control.Label
        InductancephaseLabel           matlab.ui.control.Label
        CapacitancephaseLabel          matlab.ui.control.Label
        ResistanceACLabel              matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Task_2Tab                      matlab.ui.container.Tab
        parametersPanel                matlab.ui.container.Panel
        ALabel                         matlab.ui.control.Label
        BLabel                         matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        DLabel                         matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        InputsPanel_2                  matlab.ui.container.Panel
        ButtonGroup2                   matlab.ui.container.ButtonGroup
        Button_2                       matlab.ui.control.RadioButton
        Pi_modelButton                 matlab.ui.control.RadioButton
        T_modelButton                  matlab.ui.control.RadioButton
        Task_3Tab                      matlab.ui.container.Tab
        InputsPanel_3                  matlab.ui.container.Panel
        VR_lineKVEditFieldLabel        matlab.ui.control.Label
        VR_lineKVEditField             matlab.ui.control.NumericEditField
        casesButtonGroup               matlab.ui.container.ButtonGroup
        Button_3                       matlab.ui.control.RadioButton
        case_1Button                   matlab.ui.control.RadioButton
        case_2Button                   matlab.ui.control.RadioButton
        case_1visualTab                matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        case_2visualTab                matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        select="none";
        d=[];
        i=0;
        distance=0;
        resistance=0 ;
        inductance=0;
        capacitance=0;
        conductor=1;
        select_model="none";
        A=0;
        B=0;
        C=0;
        D=0;
        vr=0;
        select_case="none";
        Voltage=[];
        Efficiency=[];
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
             
            app.numberofconductorsEditField.Enable = false;
            app.EnterButton.Enable = false ;
            
            switch(app.select)
              
              case "Symmetric"
                 app.UnsymmetricButton.Enable='off';
                 app.SymmetricButton.Enable='off';
                 app.EnterButton.Enable = true ; 
                 app.numberofconductorsEditField.Enable = false;
                 if (app.i==1)
                 app.EnterButton.Enable = false ;
                 app.resistance=resistance_calc(app.conductorresistivityEditField.Value,app.conductorlengthKmEditField.Value,app.ConductordiametercmEditField.Value);               
                 app.inductance=inductance_calc(app.d(1),app.ConductordiametercmEditField.Value);
                 app.capacitance=capacitance_calc(app.d(1),app.ConductordiametercmEditField.Value);
                 end 
                  
              case "Unsymmetric"
                 app.UnsymmetricButton.Enable='off';
                 app.SymmetricButton.Enable='off';
                 app.EnterButton.Enable = true ;  
                 app.numberofconductorsEditField.Enable = true;
                 if (app.i==app.conductor)
                 app.EnterButton.Enable = false ;
                 deq=(prod(app.d))^(1/app.conductor);
                 app.resistance=resistance_calc(app.conductorresistivityEditField.Value,app.conductorlengthKmEditField.Value,app.ConductordiametercmEditField.Value);               
                 app.inductance=inductance_calc(deq,app.ConductordiametercmEditField.Value);
                 app.capacitance=capacitance_calc(deq,app.ConductordiametercmEditField.Value);
                 end 
                  
            end
            if (app.i==app.conductor)
            app.Label.Text = string(app.resistance)+" ohm";
            app.Label_12.Text = string(app.resistance*1.1)+" ohm";
            app.Label_2.Text = string(app.inductance)+" H/Km";
            app.Label_3.Text = string(app.capacitance)+" F/Km";
            end 
           if (app.conductorlengthKmEditField.Value<80)
              app.Pi_modelButton.Enable='off';
              app.T_modelButton.Enable='off'; 
           else 
              app.Pi_modelButton.Enable='on';
              app.T_modelButton.Enable='on'; 
           end 
           
     
         [app.A,app.B,app.C,app.D]=calc_abcd(app.capacitance,app.inductance,app.resistance,app.conductorlengthKmEditField.Value,app.select_model,app.FrequencyHZEditField.Value) ;
        if (app.select_model~="none" || app.conductorlengthKmEditField.Value<80 )
          app.Label_8.Text = string(app.A);
          app.Label_9.Text = string(app.B);
          app.Label_10.Text = string(app.C);
          app.Label_11.Text = string(app.D);
        end
        switch(app.select_case) 
            case "case_1"
                  PR = linspace(0, 100, 100000);
                  powerfactor = 0.8;
                  app.Voltage = zeros(size(PR));
                  app.Efficiency = zeros(size(PR));
                  %app.vr = 1000 * app.vr;
                  vr = 1000 * app.vr;

                   for z = 1:length(PR)
                        value = PR(z);
                        S = value * 1000 / powerfactor;  % Division corrected to use element-wise operation
                        VR = vr / sqrt(3);
                        IR = (S ./ (3 * VR)) * exp(1i * (-1 * acos(powerfactor)));
                        VS = app.A * VR + app.B * IR;
                        IS = app.C * VR + app.D * IR;
                        PS = (3 * (abs(VS)) .* (abs(IS)) .* (cos(angle(VS) - angle(IS))));
                        V_noload = abs(VS) .* sqrt(3) / abs(app.A);
                        app.Voltage(z) = (abs(V_noload) - abs(vr)) ./ abs(vr) * 100;
                        app.Efficiency(z) = (value * 1000 / abs(PS)) * 100;
                  
                   end
             

                plot(app.UIAxes, PR, app.Efficiency);
                plot(app.UIAxes2, PR, app.Voltage);
                app.Voltage(1:end) = 0;
                app.Efficiency(1:end) = 0;
    
            case "case_2"
    
                    PR = 100000;
                    powerfactor = 0.3:0.01:1;
                    %lagVoltageMatrix = zeros(size(powerfactor));
                    %lagEfficiencyMatrix = zeros(size(powerfactor));
                    %leadVoltageMatrix = zeros(size(powerfactor));
                    %leadEfficiencyMatrix = zeros(size(powerfactor));
                    
                    vr = 1000 * app.vr;
                    
                    for z = 1:length(powerfactor)
                        S = PR  / powerfactor(z);  % Division corrected to use element-wise operation
                        VR = vr / sqrt(3);
                        IR = (S ./ (3 * VR)) * exp(1i * (-1 * acos(powerfactor(z))));
                        VS = app.A * VR + app.B * IR;
                        IS = app.C * VR + app.D * IR;
                        PS = (3 * (abs(VS)) .* (abs(IS)) .* (cos(angle(VS) - angle(IS))));
                        V_noload = abs(VS) * sqrt(3) / abs(app.A);
                        lagVoltageMatrix(z) = (abs(V_noload) - abs(vr)) / abs(vr) * 100;
                        lagEfficiencyMatrix(z) = (PR / abs(PS)) * 100;
                    end
                    
                    for z = 1:length(powerfactor)
                        S = PR  / powerfactor(z);  % Division corrected to use element-wise operation
                        VR = vr / sqrt(3);
                        IR = (S ./ (3 * VR)) * exp(1i * (1 * acos(powerfactor(z))));
                        VS = app.A * VR + app.B * IR;
                        IS = app.C * VR + app.D * IR;
                        PS = (3 * (abs(VS)) .* (abs(IS)) .* (cos(angle(VS) - angle(IS))));
                        V_noload = abs(VS) * sqrt(3) / abs(app.A);
                        leadVoltageMatrix(z) = (abs(V_noload) - abs(vr)) / abs(vr) * 100;
                        leadEfficiencyMatrix(z) = (PR / abs(PS)) * 100;
                    end
                    
                   
                    plot(app.UIAxes3,powerfactor, lagEfficiencyMatrix);
                    plot(app.UIAxes7,powerfactor, lagVoltageMatrix);
                    plot(app.UIAxes5,powerfactor, leadEfficiencyMatrix);
                    plot(app.UIAxes6,powerfactor, leadVoltageMatrix);

                    lagVoltageMatrix(1:end) = 0;
                    lagEfficiencyMatrix(1:end) = 0;
                    leadVoltageMatrix(1:end) = 0;
                    leadEfficiencyMatrix(1:end) = 0;
            
        end
        
        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            app.distance = app.DistancemEditField.Value;
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.d=[app.d app.distance];
            app.i=app.i+1;
            startupFcn(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.select = app.ButtonGroup.SelectedObject.Text;
            startupFcn(app);
        end

        % Value changed function: numberofconductorsEditField
        function numberofconductorsEditFieldValueChanged(app, event)
            app.conductor = app.numberofconductorsEditField.Value;
            startupFcn(app);
        end

        % Callback function: ButtonGroup2, 
        % ConductordiametercmEditField
        function ButtonGroup2SelectionChanged(app, event)
            app.select_model = app.ButtonGroup2.SelectedObject.Text;
            startupFcn(app);
        end

        % Value changed function: VR_lineKVEditField
        function VR_lineKVEditFieldValueChanged(app, event)
            app.vr = app.VR_lineKVEditField.Value;
            startupFcn(app);
        end

        % Selection changed function: casesButtonGroup
        function casesButtonGroupSelectionChanged(app, event)
            app.select_case = app.casesButtonGroup.SelectedObject.Text;
            startupFcn(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Task_1Tab = uitab(app.TabGroup);
            app.Task_1Tab.Title = 'Task_1';
            app.InputsPanel = uipanel(app.Task_1Tab);
            app.InputsPanel.Title = 'Inputs';
            app.conductorresistivityEditFieldLabel = uilabel(app.InputsPanel);
            app.conductorresistivityEditFieldLabel.Text = 'conductor resistivity';
            app.conductorresistivityEditField = uieditfield(app.InputsPanel, 'numeric');
            app.conductorlengthKmEditFieldLabel = uilabel(app.InputsPanel);
            app.conductorlengthKmEditFieldLabel.Text = 'conductor length(Km)';
            app.conductorlengthKmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ConductordiametercmEditFieldLabel = uilabel(app.InputsPanel);
            app.ConductordiametercmEditFieldLabel.Text = 'Conductor diameter (cm)';
            app.ConductordiametercmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ConductordiametercmEditField.ValueChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.FrequencyHZEditFieldLabel = uilabel(app.InputsPanel);
            app.FrequencyHZEditFieldLabel.Text = 'Frequency (HZ)';
            app.FrequencyHZEditField = uieditfield(app.InputsPanel, 'numeric');
            app.DinputPanel = uipanel(app.Task_1Tab);
            app.DinputPanel.Title = 'D input';
            app.ButtonGroup = uibuttongroup(app.DinputPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Enable = 'off';
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.SymmetricButton = uiradiobutton(app.ButtonGroup);
            app.SymmetricButton.Text = 'Symmetric';
            app.UnsymmetricButton = uiradiobutton(app.ButtonGroup);
            app.UnsymmetricButton.Text = 'Unsymmetric';
            app.DistancemEditFieldLabel = uilabel(app.DinputPanel);
            app.DistancemEditFieldLabel.Text = 'Distance(m)';
            app.DistancemEditField = uieditfield(app.DinputPanel, 'numeric');
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.EnterButton = uibutton(app.DinputPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.numberofconductorsEditFieldLabel = uilabel(app.DinputPanel);
            app.numberofconductorsEditFieldLabel.Text = 'number of conductors';
            app.numberofconductorsEditField = uieditfield(app.DinputPanel, 'numeric');
            app.numberofconductorsEditField.ValueChangedFcn = createCallbackFcn(app, @numberofconductorsEditFieldValueChanged, true);
            app.Label = uilabel(app.Task_1Tab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Task_1Tab);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Task_1Tab);
            app.Label_3.Text = '';
            app.ResistanceDCLabel = uilabel(app.Task_1Tab);
            app.ResistanceDCLabel.Text = 'Resistance (DC):';
            app.InductancephaseLabel = uilabel(app.Task_1Tab);
            app.InductancephaseLabel.Text = 'Inductance/phase:';
            app.CapacitancephaseLabel = uilabel(app.Task_1Tab);
            app.CapacitancephaseLabel.Text = 'Capacitance/phase:';
            app.ResistanceACLabel = uilabel(app.Task_1Tab);
            app.ResistanceACLabel.Text = 'Resistance (AC):';
            app.Label_12 = uilabel(app.Task_1Tab);
            app.Label_12.Text = '';
            app.Task_2Tab = uitab(app.TabGroup);
            app.Task_2Tab.Title = 'Task_2';
            app.parametersPanel = uipanel(app.Task_2Tab);
            app.parametersPanel.Title = 'parameters';
            app.ALabel = uilabel(app.parametersPanel);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.parametersPanel);
            app.BLabel.Text = 'B';
            app.CLabel = uilabel(app.parametersPanel);
            app.CLabel.Text = 'C';
            app.DLabel = uilabel(app.parametersPanel);
            app.DLabel.Text = 'D';
            app.Label_4 = uilabel(app.parametersPanel);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.parametersPanel);
            app.Label_5.Text = '=';
            app.Label_6 = uilabel(app.parametersPanel);
            app.Label_6.Text = '=';
            app.Label_7 = uilabel(app.parametersPanel);
            app.Label_7.Text = '=';
            app.Label_8 = uilabel(app.parametersPanel);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.parametersPanel);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.parametersPanel);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.parametersPanel);
            app.Label_11.Text = '';
            app.InputsPanel_2 = uipanel(app.Task_2Tab);
            app.InputsPanel_2.Title = 'Inputs';
            app.ButtonGroup2 = uibuttongroup(app.InputsPanel_2);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.ButtonGroup2.Title = 'Button Group2';
            app.Button_2 = uiradiobutton(app.ButtonGroup2);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Pi_modelButton = uiradiobutton(app.ButtonGroup2);
            app.Pi_modelButton.Text = 'Pi_model';
            app.T_modelButton = uiradiobutton(app.ButtonGroup2);
            app.T_modelButton.Text = 'T_model';
            app.Task_3Tab = uitab(app.TabGroup);
            app.Task_3Tab.Title = 'Task_3';
            app.InputsPanel_3 = uipanel(app.Task_3Tab);
            app.InputsPanel_3.Title = 'Inputs';
            app.VR_lineKVEditFieldLabel = uilabel(app.InputsPanel_3);
            app.VR_lineKVEditFieldLabel.Text = 'VR_line (KV)';
            app.VR_lineKVEditField = uieditfield(app.InputsPanel_3, 'numeric');
            app.VR_lineKVEditField.ValueChangedFcn = createCallbackFcn(app, @VR_lineKVEditFieldValueChanged, true);
            app.casesButtonGroup = uibuttongroup(app.InputsPanel_3);
            app.casesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @casesButtonGroupSelectionChanged, true);
            app.casesButtonGroup.Title = 'cases';
            app.Button_3 = uiradiobutton(app.casesButtonGroup);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = 'Button';
            app.Button_3.Value = true;
            app.case_1Button = uiradiobutton(app.casesButtonGroup);
            app.case_1Button.Text = 'case_1';
            app.case_2Button = uiradiobutton(app.casesButtonGroup);
            app.case_2Button.Text = 'case_2';
            app.case_1visualTab = uitab(app.TabGroup);
            app.case_1visualTab.Title = 'case_1 visual';
            app.UIAxes = uiaxes(app.case_1visualTab);
            title(app.UIAxes, 'Efficiency VS Active power')
            xlabel(app.UIAxes, 'Active power(KW)')
            ylabel(app.UIAxes, 'Efficiency')
            app.UIAxes2 = uiaxes(app.case_1visualTab);
            title(app.UIAxes2, 'Voltage regulation VS Active power')
            xlabel(app.UIAxes2, 'Active power(KW)')
            ylabel(app.UIAxes2, 'Voltage regulation')
            app.case_2visualTab = uitab(app.TabGroup);
            app.case_2visualTab.Title = 'case_2 visual';
            app.UIAxes3 = uiaxes(app.case_2visualTab);
            title(app.UIAxes3, 'Efficiency VS Lag PF')
            xlabel(app.UIAxes3, 'Lag PF')
            ylabel(app.UIAxes3, 'Efficiency ')
            app.UIAxes5 = uiaxes(app.case_2visualTab);
            title(app.UIAxes5, 'Efficiency VS Lead PF')
            xlabel(app.UIAxes5, 'Lead PF')
            ylabel(app.UIAxes5, 'Efficiency ')
            app.UIAxes6 = uiaxes(app.case_2visualTab);
            title(app.UIAxes6, 'Voltage regulation VS Lead PF')
            xlabel(app.UIAxes6, 'Lead PF')
            ylabel(app.UIAxes6, 'Voltage regulation')
            app.UIAxes7 = uiaxes(app.case_2visualTab);
            title(app.UIAxes7, 'Voltage regulation VS Lag PF')
            xlabel(app.UIAxes7, 'Lag PF')
            ylabel(app.UIAxes7, 'Voltage regulation')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Power

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end